classdef MuscleSimulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EMGSimulatorUIFigure            matlab.ui.Figure
        EMGPatternSelectionButtonGroup  matlab.ui.container.ButtonGroup
        MotionButton                    matlab.ui.control.RadioButton
        MuscleButton                    matlab.ui.control.RadioButton
        MuscleSelectionDropDown         matlab.ui.control.DropDown
        MotionSelectionDropDown         matlab.ui.control.DropDown
        ActivateEMGSwitchLabel          matlab.ui.control.Label
        ActivateEMGSwitch               matlab.ui.control.Switch
        Label                           matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        SaveTrainingDataButton          matlab.ui.control.Button
    end


    properties (Access = public)
        EmgSource;
    end
    
    
    properties (Access = private)
       
        % Channel properties
        % NOTE: RIGHT NOW NEED TO BE VERY CAREFUL CHANGINE THESE AS THESE ARE HARDCODED TO CORRESPOND TO THE EMGPATTERN FILE AND ALSO THE PROTOCOL FOR SENDING OUT WHICH MUSCLES ARE ACTIVATES
       Muscles = {'Flexor Carpi Ulnaris', 'Palmaris Longus', 'Flexor Carpi Radialis', 'Pronator Teres', 'Brachioradialis', 'Extensor Carpi Radialis Longus', 'Extensor Carpi Radialis Brevis', 'Extensor Digitorum Communis', 'Extensor Digiti Minimi', 'Extensor Carpi Ulnaris', 'Biceps Brachii', 'Coracobrachialis', 'Brachialis', 'Triceps Brachii'}; % Description
       Motions = {'Elbow Flexion Pattern (Biceps, etc.)', 'Elbow Extension Pattern (Triceps, etc.)','Wrist Flexion Pattern (Flexor Carpi Ulnaris, etc.)', 'Wrist Extension Pattern (Brachioradialis, etc.)', 'Hand Open Pattern', 'Tip Grasp Pattern', 'Power Grasp Pattern'};
       % TODO: THESE COULD BE NON-BINARY TO INDICATE DEGREE TO WHICH THEY ARE ACTIVATING
       MuscleMotionMap = [0 0 0 0 0 0 0 0 0 0 1 1 1 0;
                          0 0 0 0 0 0 0 0 0 0 0 0 0 1;
                          1 1 1 1 0 0 0 0 0 0 0 0 0 0;
                          0 0 0 0 1 1 1 1 1 1 0 0 0 0;
                          0 0 0 0 0 0 0 1 1 0 0 0 0 0
                          0 0 1 0 0 0 0 1 1 0 0 0 0 0
                          0 0 1 0 1 0 0 0 0 0 0 0 0 0
                          0 0 0 0 0 0 0 0 0 0 0 0 0 0]; % NumMotions x NumMuscles
       
       % Pattern tracker
       EmgPatternFileName = 'emgPatternData2.mat';
       NoMotionPattern = 8;
       ActivePattern = 1;

       % Streaming properties
       Timer;
       isRunning = false;
       MuscleSink;
       %EmgSink;
       
    end
    
    properties (Dependent)
        NumChannelsToMap;
        NumMuscles;
        NumMotions;
    end
    
    methods
         % Get Methods
        function value = get.NumMuscles(app)
            value = length(app.Muscles);
        end
        function value = get.NumMotions(app)
            value = length(app.Motions);
        end
        
    end
    methods (Access = private)  

        function PutData(app)
%                 emg = typecast(int8(120*(app.EmgSource.getData(1)-1.2)), 'uint8');
%                 quat = typecast(single([1 0 0 0]),'uint8');
%                 acc = typecast(single([0 0 0]),'uint8');
%                 gyro = typecast(single([0 0 0]),'uint8');
%                 msg = cat(2,emg,quat,acc,gyro);
%                 app.Sink.putData(msg)
            
            % Send out muscle activation index
            selectedButton = app.EMGPatternSelectionButtonGroup.SelectedObject;
            
            switch selectedButton
                case app.MotionButton
                    motionIdx = find(strcmp(app.MotionSelectionDropDown.Items, app.MotionSelectionDropDown.Value));
                    muscleIdx = app.MuscleMotionMap(motionIdx,:);
                case app.MuscleButton
                    muscleIdx = zeros(1,size(app.MuscleMotionMap,2));
                    selectedIdx = find(strcmp(app.MuscleSelectionDropDown.Items, app.MuscleSelectionDropDown.Value));
                    muscleIdx(selectedIdx) = 1;
            end
            
            msg = typecast(muscleIdx, 'uint8');
%             app.MuscleSink.putData(msg);
            
            %muscleIdx
            
            
            %pause(1/200)
        end
        
        function StartUdpStream(app)
            

%             %% Stream simulator with intent of emulating Myo Arm Band
            
            % TODO: Make a dropdown menu for setting port or at least make a property
            
%             app.EmgSource = Inputs.SignalSimulator();
%             app.EmgSource.EnableGui = false;
%             app.EmgSource.initialize();
%             app.EmgSource.setPattern(0);
            

%             app.EmgSource = Inputs.EmgSimulator(app.EmgPatternFileName);
%             app.EmgSource.initialize();
%             app.EmgSource.setPattern(9); % No movement class
            
            
%             UdpLocalPort = 56789;
%             UdpDestinationPort = 10001;
%             UdpAddress = '127.0.0.1'; % '192.168.1.101';
%             % PnetClass(localPort,remotePort,remoteIP)
%             app.EmgSink = PnetClass(UdpLocalPort,UdpDestinationPort,UdpAddress);
%             app.EmgSink.initialize()
% 

%             UdpLocalPort = 56789;
%             UdpDestinationPort = 10009;
%             UdpAddress = '127.0.0.1'; % '192.168.1.101';
%             % PnetClass(localPort,remotePort,remoteIP)
%             app.MuscleSink = PnetClass(UdpLocalPort,UdpDestinationPort,UdpAddress);
%             app.MuscleSink.initialize()

        end
 
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize dropdown menus
            app.MuscleSelectionDropDown.Items = app.Muscles;
            app.MotionSelectionDropDown.Items = app.Motions;
            if length (app.MuscleSelectionDropDown.Items) > 15
               warning('More than 15 muscles supplied and only 15 unique signals available');
            end
            if length (app.MotionSelectionDropDown.Items) > 15
               warning('More than 15 motions supplied and only 15 unique signals available');
            end
            
            app.EmgSource = Inputs.EmgSimulator(app.EmgPatternFileName, 0);
            app.EmgSource.initialize();
            app.EmgSource.start();
            app.EmgSource.setPattern(app.NoMotionPattern); % No movement class
            
            % Start Udp stream, it will always be streaming while this GUI is on
            app.StartUdpStream();
            app.Timer = UiTools.create_timer(mfilename,@(src,evt)app.PutData);
            app.Timer.Period = 0.1;
            start(app.Timer);
            
        end

        % Selection changed function: EMGPatternSelectionButtonGroup
        function ModeChanged2(app, event)
            selectedButton = app.EMGPatternSelectionButtonGroup.SelectedObject;
            
            switch selectedButton
                case app.MotionButton
                    app.MuscleSelectionDropDown.Enable = 0;
                    app.MotionSelectionDropDown.Enable = 1;
                    app.ActivateEMGSwitch.Value = 'Off';
                case app.MuscleButton
                    app.MuscleSelectionDropDown.Enable = 1;
                    app.MotionSelectionDropDown.Enable = 0;
                    app.ActivateEMGSwitch.Value = 'Off';
            end
            
        end

        % Value changed function: MotionSelectionDropDown
        function MotionChanged(app, event)
            value = app.MotionSelectionDropDown.Value;
            idx = find(strcmp(app.MotionSelectionDropDown.Items, value));
            app.ActivePattern = idx;
            
            value = app.ActivateEMGSwitch.Value;
            switch value
                case 'Off'
                    app.EmgSource.setPattern(app.NoMotionPattern);
                case 'On'
                    app.EmgSource.setPattern(app.ActivePattern);
            end
            
            
        end

        % Value changed function: MuscleSelectionDropDown
        function MuscleChanged(app, event)
            value = app.MuscleSelectionDropDown.Value;
            idx = find(strcmp(app.MuscleSelectionDropDown.Items, value));
            app.ActivePattern = idx;
            
            value = app.ActivateEMGSwitch.Value;
            switch value
                case 'Off'
                    app.EmgSource.setPattern(app.NoMotionPattern);
                case 'On'
                    app.EmgSource.setPattern(app.ActivePattern);
            end
        end

        % Value changed function: ActivateEMGSwitch
        function ActivationChanged2(app, event)
            value = app.ActivateEMGSwitch.Value;
            
            switch value
                case 'Off'
                    app.EmgSource.setPattern(app.NoMotionPattern);
                    app.Lamp.Color = [0.94,0.94,0.94];
                case 'On'
                    app.EmgSource.setPattern(app.ActivePattern);
                    app.Lamp.Color = [0,1,0];
            end
            
        end

        % Close request function: EMGSimulatorUIFigure
        function EMGSimulatorUIFigureCloseRequest(app, event)
            %stop(app.Timer);
            app.EmgSource.stop();
            %app.MuscleSink.close();
            %app.EmgSink.close();
            delete(app)
        end

        % Button pushed function: SaveTrainingDataButton
        function saveTrainingData(app, event)
            [file, path] = uiputfile('*.trainingData', 'Select Location to Save Training Data');
            basePath = fileparts(which('MiniVIE'));
            oldFile = fullfile(basePath,'+Inputs','@EmgSimulator','private', 'DEFAULT.trainingData');
            copyfile(oldFile, fullfile(path, file), 'f');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EMGSimulatorUIFigure = uifigure('Visible', 'off');
            app.EMGSimulatorUIFigure.Name = 'EMG Simulator';
            app.EMGSimulatorUIFigure.CloseRequestFcn = createCallbackFcn(app, @EMGSimulatorUIFigureCloseRequest, true);
            app.EMGPatternSelectionButtonGroup = uibuttongroup(app.EMGSimulatorUIFigure);
            app.EMGPatternSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModeChanged2, true);
            app.EMGPatternSelectionButtonGroup.Title = 'EMG Pattern Selection';
            app.MotionButton = uiradiobutton(app.EMGPatternSelectionButtonGroup);
            app.MotionButton.Text = {'Motion'; ''};
            app.MotionButton.Value = true;
            app.MuscleButton = uiradiobutton(app.EMGPatternSelectionButtonGroup);
            app.MuscleButton.Enable = 'off';
            app.MuscleButton.Text = 'Muscle';
            app.MuscleSelectionDropDown = uidropdown(app.EMGPatternSelectionButtonGroup);
            app.MuscleSelectionDropDown.Items = {'NA'};
            app.MuscleSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @MuscleChanged, true);
            app.MuscleSelectionDropDown.Enable = 'off';
            app.MuscleSelectionDropDown.Value = 'NA';
            app.MotionSelectionDropDown = uidropdown(app.EMGPatternSelectionButtonGroup);
            app.MotionSelectionDropDown.Items = {'NA'};
            app.MotionSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @MotionChanged, true);
            app.MotionSelectionDropDown.Value = 'NA';
            app.ActivateEMGSwitchLabel = uilabel(app.EMGSimulatorUIFigure);
            app.ActivateEMGSwitchLabel.Text = {'Activate EMG'; ''};
            app.ActivateEMGSwitch = uiswitch(app.EMGSimulatorUIFigure, 'slider');
            app.ActivateEMGSwitch.ValueChangedFcn = createCallbackFcn(app, @ActivationChanged2, true);
            app.Label = uilabel(app.EMGSimulatorUIFigure);
            app.Label.Text = '';
            app.Lamp = uilamp(app.EMGSimulatorUIFigure);
            app.SaveTrainingDataButton = uibutton(app.EMGSimulatorUIFigure, 'push');
            app.SaveTrainingDataButton.ButtonPushedFcn = createCallbackFcn(app, @saveTrainingData, true);
            app.SaveTrainingDataButton.Text = 'Save Training Data';
            % Show the figure after all components are created
            app.EMGSimulatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MuscleSimulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EMGSimulatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EMGSimulatorUIFigure)
        end
    end
end